classdef testgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UI                         matlab.ui.Figure
        ImSource                   matlab.ui.control.UIAxes
        ImOut                      matlab.ui.control.UIAxes
        LoadImageButton            matlab.ui.control.Button
        EmotionDropDownLabel       matlab.ui.control.Label
        EmotionDropDown            matlab.ui.control.DropDown
        ManualFeaturePointsButton  matlab.ui.control.Button
        AutoFeaturePointsButton    matlab.ui.control.Button
        RunButton                  matlab.ui.control.Button
    end


    properties (Access = private)
        loaded % Check if image is loaded
        emotion % For user to select emotion
        points % Check if feature points allocated
        leftEyeFP
        rightEyeFP
        mouthFP
        leftEyebrowFP
        rightEyebrowFP
        oriFace
        fullpath
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.loaded = 0;
            app.emotion = 1;
            app.points = 0;
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            app.loaded = 1;
            app.points = 1;
            [file,path] = uigetfile({'*.jpeg;*.png;*.tiff;*.jpg';'*.*'},'File Selector');
            app.fullpath = strcat(path,file);
            title(app.ImSource, file)
            xlabel(app.ImSource, 'Neutral')
            ylabel(app.ImSource, '')
            imshow(app.fullpath,'Parent',app.ImSource);
            app.UI.Visible = 'off';
            app.UI.Visible = 'on';
        end

        % Value changed function: EmotionDropDown
        function EmotionDropDownValueChanged(app, event)
            app.emotion = app.EmotionDropDown.Value;
        end

        % Button pushed function: ManualFeaturePointsButton
        function ManualFeaturePointsButtonPushed(app, event)
            if app.loaded == 0
                LoadImageButtonPushed(app,event)
            end
            app.oriFace = imread(app.fullpath);
            [app.leftEyeFP, app.rightEyeFP, app.mouthFP, app.leftEyebrowFP, app.rightEyebrowFP, app.oriFace] = manualfeaturePoints(app.fullpath);
            feature_points = [app.leftEyeFP; app.rightEyeFP; app.mouthFP; app.leftEyebrowFP; app.rightEyebrowFP];
            marked_image = insertMarker(app.oriFace,feature_points, 'o', 'color','red','size',1);        
            imshow(marked_image,'Parent',app.ImSource);
            app.UI.Visible = 'off';
            app.UI.Visible = 'on';
            app.points = 1;
        end

        % Button pushed function: AutoFeaturePointsButton
        function AutoFeaturePointsButtonPushed(app, event)
            if app.loaded == 0
                LoadImageButtonPushed(app,event)
            end
            try
                %f = uifigure('Position',[200 300 400 75]);
                %d = uiprogressdlg(f,'Title','Extracting Feature Points','Indeterminate','on');
                [app.leftEyeFP, app.rightEyeFP, app.mouthFP, app.leftEyebrowFP, app.rightEyebrowFP, app.oriFace] = getfeaturePoints(app.fullpath);
                feature_points = [app.leftEyeFP; app.rightEyeFP; app.mouthFP; app.leftEyebrowFP; app.rightEyebrowFP];
                marked_image = insertMarker(app.oriFace,feature_points, 'o', 'color','red','size',1);        
                imshow(marked_image,'Parent',app.ImSource);
                app.points = 1;
                %close(f);
                %close(d);
            catch ME
                message = sprintf('Could not extract feature points, please select manually');
                uiwait(warndlg(message));
            end
            app.UI.Visible = 'off';
            app.UI.Visible = 'on';
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if app.loaded == 1 && app.points == 1
                if app.emotion == 1
                    [leftEyeX,leftEyeY] = splitpoints(app.leftEyeFP);
                    [leftEyebrowX,leftEyebrowY] = splitpoints(app.leftEyebrowFP);
                    [rightEyeX,rightEyeY] = splitpoints(app.rightEyeFP);
                    [rightEyebrowX,rightEyebrowY] = splitpoints(app.rightEyebrowFP);
                    [mouthX,mouthY] = splitpoints(app.mouthFP);
                    %f = uifigure('Position',[200 300 400 75]);
                    %d = uiprogressdlg(f,'Title','Warping Image','Indeterminate','on');
                    try
                        imout = warper(app.oriFace,leftEyeX,leftEyeY,leftEyebrowX,leftEyebrowY,rightEyeX,rightEyeY,rightEyebrowX,rightEyebrowY,mouthX,mouthY);
                        imshow(imout,'Parent',app.ImOut);
                    catch ME
                        message = sprintf('Feature Points selected not appropriate, please select manually');
                        uiwait(warndlg(message));
                    end
                    %close(f);
                    %close(d);
                    app.UI.Visible = 'off';
                    app.UI.Visible = 'on';
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UI = uifigure;
            app.UI.Name = 'Emotion Generator';
            app.ImSource = uiaxes(app.UI);
            title(app.ImSource, 'Source')
            xlabel(app.ImSource, 'X')
            ylabel(app.ImSource, 'Y')
            app.ImOut = uiaxes(app.UI);
            title(app.ImOut, 'Output')
            xlabel(app.ImOut, 'X')
            ylabel(app.ImOut, 'Y')
            app.LoadImageButton = uibutton(app.UI, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.EmotionDropDownLabel = uilabel(app.UI);
            app.EmotionDropDownLabel.Text = 'Emotion';
            app.EmotionDropDown = uidropdown(app.UI);
            app.EmotionDropDown.Items = {'Happy', 'Sad(n/a)', 'Angry(n/a)', 'Disgust(n/a)', 'Fear(n/a)', 'Surprise(n/a)'};
            app.EmotionDropDown.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.EmotionDropDown.ValueChangedFcn = createCallbackFcn(app, @EmotionDropDownValueChanged, true);
            app.EmotionDropDown.Value = '1';
            app.ManualFeaturePointsButton = uibutton(app.UI, 'push');
            app.ManualFeaturePointsButton.ButtonPushedFcn = createCallbackFcn(app, @ManualFeaturePointsButtonPushed, true);
            app.ManualFeaturePointsButton.Text = 'Manual Feature Points';
            app.AutoFeaturePointsButton = uibutton(app.UI, 'push');
            app.AutoFeaturePointsButton.ButtonPushedFcn = createCallbackFcn(app, @AutoFeaturePointsButtonPushed, true);
            app.AutoFeaturePointsButton.Text = 'Auto Feature Points';
            app.RunButton = uibutton(app.UI, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
        end
    end

    methods (Access = public)

        % Construct app
        function app = testgui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UI)
        end
    end
end